classdef P3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        LengthofTrackEditFieldLabel   matlab.ui.control.Label
        LengthofTrackEditField        matlab.ui.control.NumericEditField
        GeneratetruetrackblueButton   matlab.ui.control.Button
        GeneratemeasurementredButton  matlab.ui.control.Button
        KalmanfiltergreenButton       matlab.ui.control.Button
        ClearfigureButton             matlab.ui.control.Button
    end


    properties (Access = private)
        Hplot % Handle to plot
    end


    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            % Tein tän mut tääl ei oo mitään
        end

        % Button pushed function: GeneratetruetrackblueButton
        function truePushed(app, event)
            x0 = zeros(4,1); %Mean of initial state
            P0 = diag([100 100 1 1]);%Covariance matrix of initial state
            Phi = [eye(2) eye(2);zeros(2,2) eye(2)];% State transition matrix
            Q = [1/3*eye(2),1/2*eye(2);1/2*eye(2),eye(2)]; % Covariance matrix of state model
            n = app.LengthofTrackEditField.Value; % length of track
            dim = 2+2;% Dimension 2D position and 2D velocity
            X=nan(dim,n+1); %Track t=0...t=n
            X(:,1)=x0;
            for t=1:n
                X(:,t+1)=Phi*X(:,t)+mvnrnd(zeros(1,4),Q)';
            end
            app.Hplot = plot(app.UIAxes, X(1,:), X(2,:), 'o-', 'MarkerSize', 2,...
            	'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'b');
        end

        % Button pushed function: ClearfigureButton
        function clearFigure(app, event)
            cla(app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kalman filter')
            xlabel(app.UIAxes, 'x-coordinate')
            ylabel(app.UIAxes, 'y-coordinate')
            app.LengthofTrackEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofTrackEditFieldLabel.Text = 'Length of Track';
            app.LengthofTrackEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratetruetrackblueButton = uibutton(app.UIFigure, 'push');
            app.GeneratetruetrackblueButton.ButtonPushedFcn = createCallbackFcn(app, @truePushed, true);
            app.GeneratetruetrackblueButton.Text = 'Generate true track (blue)';
            app.GeneratemeasurementredButton = uibutton(app.UIFigure, 'push');
            app.GeneratemeasurementredButton.Text = 'Generate measurement (red)';
            app.KalmanfiltergreenButton = uibutton(app.UIFigure, 'push');
            app.KalmanfiltergreenButton.Text = 'Kalman filter (green)';
            app.ClearfigureButton = uibutton(app.UIFigure, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @clearFigure, true);
            app.ClearfigureButton.Text = 'Clear figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = P3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end